classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        TabGroup               matlab.ui.container.TabGroup       % Part_1,...
        find_root              matlab.ui.container.Tab            % Part_1
        LabelEditField         matlab.ui.control.Label            % Eqn.
        equation               matlab.ui.control.EditField       
        method                 matlab.ui.control.DropDown         % Bisecti...
        Label                  matlab.ui.control.Label            % i
        Label2                 matlab.ui.control.Label            % xL
        Label3                 matlab.ui.control.Label            % xU
        Label4                 matlab.ui.control.Label            % eps
        Label5                 matlab.ui.control.Label            % root
        LabelEditField2        matlab.ui.control.Label            % File
        File                   matlab.ui.control.EditField       
        LabelNumericEditField  matlab.ui.control.Label            % iteration
        iteration              matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % eps
        eps                    matlab.ui.control.NumericEditField % [0 1]
        LabelNumericEditField3 matlab.ui.control.Label            % XL
        xl                     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % XU
        xu                     matlab.ui.control.NumericEditField % [-Inf Inf]
        solve_eqn              matlab.ui.control.Button           % Solve Eqn.
        solve_file             matlab.ui.control.Button           % Solve File
        Txu                    matlab.ui.control.TextArea        
        Troot                  matlab.ui.control.TextArea        
        Txl                    matlab.ui.control.TextArea        
        Teps                   matlab.ui.control.TextArea        
        Ti                     matlab.ui.control.TextArea        
        error                  matlab.ui.control.TextArea         % Error !
        linear_equation        matlab.ui.container.Tab            % Part_2
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.mainWindow.Position = [295 161 654 327];
        end

        % solve_eqn button pushed function
        function solve_eqnButtonPushed(app)
            eqn = app.equation.Value;
            f = str2func(['@(x)' vectorize(char(eqn))]);
            i = app.iteration.Value;
            
            val = app.method.Value;
            if strcmp(val,'Bisection')
                [root,ea,i,xLower,xUpper] = bisection(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
            elseif strcmp(val,'False-Position')
                [root,ea,i,xLower,xUpper] = false_position(f,app.xl.Value,app.xu.Value,app.eps.Value,app.iteration.Value);
            else
                %set(app.Panel1,'visible','on')
            end
            if (i == 0)
                app.error.Value = 'no bracket!'; 
            else 
                
                x = [];
            for t = 1:i
                x = [x, num2str(t)];
                disp(x);
                x = [x, char(10)];
                disp(x);
            end
            disp(x);
            app.Ti.Value = num2str(x);
            x=[];
            for t = xLower
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Txl.Value = num2str(x);
            x=[];
            for t = xUpper
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Txu.Value = num2str(x);
            x=[];
            for t = root
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Troot.Value = num2str(x);
            x=[];
            for t = ea
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Teps.Value = num2str(x);
            
            if strcmp(val,'Bisection')
                plotting_bisection(f,root,xLower,xUpper,i);
            elseif strcmp(val,'False-Position')
                plotting_false_position(f,root,xLower,xUpper,i);
            else
                %set(app.Panel1,'visible','on')
            end


            end
            
        end

        % solve_file button pushed function
        function solve_fileButtonPushed(app)
            file = app.File.Value;
            fileID = fopen(file,'r');
            tline = fgetl(fileID);
            x = [tline];
            
            while ischar(tline)
                tline = fgetl(fileID);
                x = [x, tline];
                
            end
            fclose(fileID);
            
            f = inline(x(1,1));
            val = x(1,2);
            disp(x(1,1));
            disp(x(1,2));
            xl = str2num(x(3));
            xu = str2num(x(4));
            if floor(x(5)) == x(5)
                i = str2num(x(5));
            else
                eps = str2num(x(5));
            end
            
            if strcmp(val,'Bisection')
                [root,ea,i,xLower,xUpper] = bisection(f,xl,xu,eps,i);
            elseif strcmp(val,'False-Position')
                [root,ea,i,xLower,xUpper] = false_position(f,xl,xu,eps,i);
            else
                %set(app.Panel1,'visible','on')
            end
            x=[];
            for t = 1:i
                x = [x, num2str(t)];
                %disp(x);
                x = [x, char(10)];
                %disp(x);
            end
            disp(x);
            app.Ti.Value = num2str(x);
            x=[];
            for t = xLower
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Txl.Value = num2str(x);
            x=[];
            for t = xUpper
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Txu.Value = num2str(x);
            x=[];
            for t = root
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Troot.Value = num2str(x);
            x=[];
            for t = ea
                x = [x, num2str(t)];
                x = [x, char(10)];
            end
            disp(x);
            app.Teps.Value = num2str(x);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.find_root = uitab(app.TabGroup);
            app.find_root.Units = 'pixels';
            app.find_root.Title = 'Part_1';
            app.LabelEditField = uilabel(app.find_root);
            app.LabelEditField.Text = 'Eqn.';
            app.equation = uieditfield(app.find_root, 'text');
            app.method = uidropdown(app.find_root);
            app.method.Items = {'Bisection', 'False-Position'};
            app.method.Value = 'Bisection';
            app.Label = uilabel(app.find_root);
            app.Label.Text = 'i';
            app.Label2 = uilabel(app.find_root);
            app.Label2.Text = 'xL';
            app.Label3 = uilabel(app.find_root);
            app.Label3.Text = 'xU';
            app.Label4 = uilabel(app.find_root);
            app.Label4.Text = 'eps';
            app.Label5 = uilabel(app.find_root);
            app.Label5.Text = 'root';
            app.LabelEditField2 = uilabel(app.find_root);
            app.LabelEditField2.Text = 'File';
            app.File = uieditfield(app.find_root, 'text');
            app.LabelNumericEditField = uilabel(app.find_root);
            app.LabelNumericEditField.Text = 'iteration';
            app.iteration = uieditfield(app.find_root, 'numeric');
            app.iteration.Limits = [0 Inf];
            app.iteration.Value = 50;
            app.LabelNumericEditField2 = uilabel(app.find_root);
            app.LabelNumericEditField2.Text = 'eps';
            app.eps = uieditfield(app.find_root, 'numeric');
            app.eps.Limits = [0 1];
            app.eps.Value = 1e-05;
            app.LabelNumericEditField3 = uilabel(app.find_root);
            app.LabelNumericEditField3.Text = 'XL';
            app.xl = uieditfield(app.find_root, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.find_root);
            app.LabelNumericEditField4.Text = 'XU';
            app.xu = uieditfield(app.find_root, 'numeric');
            app.solve_eqn = uibutton(app.find_root, 'push');
            app.solve_eqn.ButtonPushedFcn = createCallbackFcn(app, @solve_eqnButtonPushed);
            app.solve_eqn.Text = 'Solve Eqn.';
            app.solve_file = uibutton(app.find_root, 'push');
            app.solve_file.ButtonPushedFcn = createCallbackFcn(app, @solve_fileButtonPushed);
            app.solve_file.Text = 'Solve File';
            app.Txu = uitextarea(app.find_root);
            app.Troot = uitextarea(app.find_root);
            app.Txl = uitextarea(app.find_root);
            app.Teps = uitextarea(app.find_root);
            app.Ti = uitextarea(app.find_root);
            app.error = uitextarea(app.find_root);
            app.error.Value = {'Error !'};
            app.linear_equation = uitab(app.TabGroup);
            app.linear_equation.Units = 'pixels';
            app.linear_equation.Title = 'Part_2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end